classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RESUMEButton              matlab.ui.control.Button
        StoppedLamp               matlab.ui.control.Lamp
        StoppedLampLabel          matlab.ui.control.Label
        DegLabel                  matlab.ui.control.Label
        MMLabel                   matlab.ui.control.Label
        CONNECTButton             matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
        EditFieldLabel            matlab.ui.control.Label
        IncrementDropDown_2       matlab.ui.control.DropDown
        IncrementDropDown_2Label  matlab.ui.control.Label
        IncrementDropDown         matlab.ui.control.DropDown
        IncrementDropDownLabel    matlab.ui.control.Label
        Button_8                  matlab.ui.control.Button
        JOINT4Label               matlab.ui.control.Label
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        JOINT3Label               matlab.ui.control.Label
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        JOINT2Label               matlab.ui.control.Label
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        JOINT1Label               matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        JOINTLabel                matlab.ui.control.Label
        CARTESIANLabel            matlab.ui.control.Label
        ToLabel_2                 matlab.ui.control.Label
        ToLabel                   matlab.ui.control.Label
        FromLabel_2               matlab.ui.control.Label
        FromLabel                 matlab.ui.control.Label
        DownButton                matlab.ui.control.Button
        UpButton                  matlab.ui.control.Button
        TeachModeCheckBox         matlab.ui.control.CheckBox
        LeftButton                matlab.ui.control.Button
        RightButton               matlab.ui.control.Button
        BackButton                matlab.ui.control.Button
        ForwardButton             matlab.ui.control.Button
        RACK1DropDown_2           matlab.ui.control.DropDown
        RACK1DropDown_2Label      matlab.ui.control.Label
        RACK2DropDown_3           matlab.ui.control.DropDown
        RACK2DropDown_3Label      matlab.ui.control.Label
        PERFORMButton_2           matlab.ui.control.Button
        RACK2DropDown             matlab.ui.control.DropDown
        RACK2DropDownLabel        matlab.ui.control.Label
        RACK1DropDown             matlab.ui.control.DropDown
        RACK1DropDownLabel        matlab.ui.control.Label
        PERFORMButton             matlab.ui.control.Button
        HOMEButton_2              matlab.ui.control.Button
        ESTOPButton               matlab.ui.control.Button
    end


    properties (Access = private)
        robot1; % Description
        cartesianDisIncrement = 10;
        jointAngIncrement = 10;
        teachMode;
        cartesianDirection;
        cartesianAxis;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.robot1.EStopDobot();
            app.StoppedLamp.Color = 'red';
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            rosinit;
            app.robot1 = DobotControl();
        end

        % Button pushed function: HOMEButton_2
        function HOMEButton_2Pushed(app, event)
            app.robot1.HomeDobot();
        end

        % Value changed function: IncrementDropDown
        function IncrementDropDownValueChanged(app, event)
            if app.IncrementDropDown.Value == '10'
                app.cartesianDisIncrement = 0.01;
            end

            if app.IncrementDropDown.Value == '5'
                app.cartesianDisIncrement = 0.005;
            end

            if app.IncrementDropDown.Value == '1'
                app.cartesianDisIncrement = 0.001;
            end
        end

        % Value changed function: IncrementDropDown_2
        function IncrementDropDown_2ValueChanged(app, event)
            app.jointAngIncrement = app.IncrementDropDown_2.Value;
            disp(app.jointAngIncrement);

            if app.IncrementDropDown_2.Value == '10'
                app.jointAngIncrement = 10;
            end

            if app.IncrementDropDown_2.Value == '5'
                app.jointAngIncrement = 5;
            end

            if app.IncrementDropDown_2.Value == '1'
                app.jointAngIncrement = 1;
            end
        end

        % Value changed function: TeachModeCheckBox
        function TeachModeCheckBoxValueChanged(app, event)
            app.teachMode = app.TeachModeCheckBox.Value;
            disp(app.TeachModeCheckBox.Value);
            disp(app.teachMode);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Z';
                app.cartesianDirection = 'Pos';

                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Z';
                app.cartesianDirection = 'Neg';

                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Y';
                app.cartesianDirection = 'Neg';

                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'Y';
                app.cartesianDirection = 'Pos';

                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'X';
                app.cartesianDirection = 'Neg';

                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.teachMode == 1
                app.cartesianAxis = 'X';
                app.cartesianDirection = 'Pos';

                app.robot1.JogDobotCartesian(app.cartesianAxis, app.cartesianDirection, app.cartesianDisIncrement);
            end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            app.StoppedLamp.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.HOMEButton_2 = uibutton(app.UIFigure, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_2Pushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.PERFORMButton = uibutton(app.UIFigure, 'push');
            app.PERFORMButton.Text = 'PERFORM';
            app.RACK1DropDownLabel = uilabel(app.UIFigure);
            app.RACK1DropDownLabel.Text = 'RACK 1';
            app.RACK1DropDown = uidropdown(app.UIFigure);
            app.RACK2DropDownLabel = uilabel(app.UIFigure);
            app.RACK2DropDownLabel.Text = 'RACK 2';
            app.RACK2DropDown = uidropdown(app.UIFigure);
            app.PERFORMButton_2 = uibutton(app.UIFigure, 'push');
            app.PERFORMButton_2.Text = 'PERFORM';
            app.RACK2DropDown_3Label = uilabel(app.UIFigure);
            app.RACK2DropDown_3Label.Text = 'RACK 2';
            app.RACK2DropDown_3 = uidropdown(app.UIFigure);
            app.RACK1DropDown_2Label = uilabel(app.UIFigure);
            app.RACK1DropDown_2Label.Text = 'RACK 1';
            app.RACK1DropDown_2 = uidropdown(app.UIFigure);
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.TeachModeCheckBox = uicheckbox(app.UIFigure);
            app.TeachModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TeachModeCheckBoxValueChanged, true);
            app.TeachModeCheckBox.Text = 'Teach Mode';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From';
            app.FromLabel_2 = uilabel(app.UIFigure);
            app.FromLabel_2.Text = 'From';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To';
            app.CARTESIANLabel = uilabel(app.UIFigure);
            app.CARTESIANLabel.Text = 'CARTESIAN';
            app.JOINTLabel = uilabel(app.UIFigure);
            app.JOINTLabel.Text = 'JOINT';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '-';
            app.JOINT1Label = uilabel(app.UIFigure);
            app.JOINT1Label.Text = 'JOINT 1:';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '-';
            app.JOINT2Label = uilabel(app.UIFigure);
            app.JOINT2Label.Text = 'JOINT 2:';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '+';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '-';
            app.JOINT3Label = uilabel(app.UIFigure);
            app.JOINT3Label.Text = 'JOINT 3:';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '+';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '-';
            app.JOINT4Label = uilabel(app.UIFigure);
            app.JOINT4Label.Text = 'JOINT 4:';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '+';
            app.IncrementDropDownLabel = uilabel(app.UIFigure);
            app.IncrementDropDownLabel.Text = 'Increment';
            app.IncrementDropDown = uidropdown(app.UIFigure);
            app.IncrementDropDown.Items = {'10', '5', '1'};
            app.IncrementDropDown.ValueChangedFcn = createCallbackFcn(app, @IncrementDropDownValueChanged, true);
            app.IncrementDropDown.Value = '10';
            app.IncrementDropDown_2Label = uilabel(app.UIFigure);
            app.IncrementDropDown_2Label.Text = 'Increment';
            app.IncrementDropDown_2 = uidropdown(app.UIFigure);
            app.IncrementDropDown_2.Items = {'10', '5', '1'};
            app.IncrementDropDown_2.ValueChangedFcn = createCallbackFcn(app, @IncrementDropDown_2ValueChanged, true);
            app.IncrementDropDown_2.Value = '10';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.MMLabel = uilabel(app.UIFigure);
            app.MMLabel.Text = 'MM';
            app.DegLabel = uilabel(app.UIFigure);
            app.DegLabel.Text = 'Deg';
            app.StoppedLampLabel = uilabel(app.UIFigure);
            app.StoppedLampLabel.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.UIFigure);
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end